classdef MultiPanelFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiPanelFigUIFigure      matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TileFlowButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton               matlab.ui.control.ToggleButton
        AutoButton                 matlab.ui.control.ToggleButton
        YLabelEditField            matlab.ui.control.EditField
        YLabelEditFieldLabel       matlab.ui.control.Label
        XLabelEditField            matlab.ui.control.EditField
        XLabelEditFieldLabel       matlab.ui.control.Label
        SubtitleEditField          matlab.ui.control.EditField
        SubtitleEditFieldLabel     matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        TitleEditField             matlab.ui.control.EditField
        TitleEditFieldLabel        matlab.ui.control.Label
        TransposeDimButtonGroup    matlab.ui.container.ButtonGroup
        ColumnmajorButton          matlab.ui.control.RadioButton
        RowmajorButton             matlab.ui.control.RadioButton
        PaddingDropDown            matlab.ui.control.DropDown
        SpacingDropDown            matlab.ui.control.DropDown
        PanelPropertiesLabel       matlab.ui.control.Label
        SpacingLabel               matlab.ui.control.Label
        PaddingLabel               matlab.ui.control.Label
        PanelLabels                matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        RightPanel                 matlab.ui.container.Panel
        XMinorTicksCheckBox        matlab.ui.control.CheckBox
        YGridLinesCheckBox         matlab.ui.control.CheckBox
        XGridLinesCheckBox         matlab.ui.control.CheckBox
        YTickLabelsCheckBox        matlab.ui.control.CheckBox
        XTickLabelsCheckBox        matlab.ui.control.CheckBox
        LegendCheckBox             matlab.ui.control.CheckBox
        AxesBoxCheckBox            matlab.ui.control.CheckBox
        YMinorTicksCheckBox        matlab.ui.control.CheckBox
        ToggleAxesPropertiesLabel  matlab.ui.control.Label
        FontSizeEditField          matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel     matlab.ui.control.Label
        TileXLabel                 matlab.ui.control.EditField
        XLabelEditField_2Label     matlab.ui.control.Label
        TileYLabel                 matlab.ui.control.EditField
        YLabelEditField_2Label     matlab.ui.control.Label
        TileTitle                  matlab.ui.control.EditField
        TitleEditField_2Label      matlab.ui.control.Label
        AxesLabels                 matlab.ui.control.Label
        SpanEditField              matlab.ui.control.EditField
        InputasrowcolLabel         matlab.ui.control.Label
        SpanEditField_2Label       matlab.ui.control.Label
        LocationEditField          matlab.ui.control.NumericEditField
        TileDropDown               matlab.ui.control.DropDown
        LocationEditFieldLabel     matlab.ui.control.Label
        TileLabel                  matlab.ui.control.Label
        TilePropertiesLabel        matlab.ui.control.Label
        SelectionLabel             matlab.ui.control.Label
        UpdateLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        MultiPanel % Handle for tiledLayout
        fig % gcf global variable
        ax % gca global variable
        currentHandle % Property to hold current handle selected
        legends % Global variable for legend label
        ColorPallet % Global variable to be changed based on colour selection
        initialX % Property to hold the initial X limits
        initialY % Property to hold the initial Y limits
        figHandles % Global variable to hold all figure handles
        AX % Property to hold all axes in separate tiles
    end

    methods (Access = private)

        % Function to find all open figures and assign handle numbers
        function findFigures(app,m,n)
            g = groot;

            if ~isempty(m) || ~isempty(n)
                delete(app.MultiPanel)
                app.MultiPanel = [];

                app.MultiPanel = tiledlayout(app.CenterPanel,m,n);
            end

            % Find all figures
            app.figHandles = gobjects(size(g));
            app.figHandles = flipud(g.Children); % Flip to start from first Figure
            sz = size(app.figHandles,1);
%             tiles = gobjects(sz,1);
            app.AX = gobjects(sz,1);

            for i=1:sz
%                 items(i) = strcat("Figure ", num2str(app.figHandles(i).Number));
%                 app.figHandles(i).CloseRequestFcn = @(src,event)deletedFigures(app,src,event);
%                 items(i,1) = nexttile(app.MultiPanel);
%                 app.figHandles(i).Children.Parent = app.MultiPanel;

                app.AX(i,1) = nexttile(app.MultiPanel);

                check = isgraphics(app.figHandles(i).Children);

                if size(check,1) > 1 % If the Children is not just the axes
                    flippedAxes = flip(app.figHandles(i).Children);
                    axesChild = flippedAxes(1); % IS THIS ALWAYS 1? AXES COMES FIRST THEN LEGEND AND OTHERS?
                else
                    axesChild = app.figHandles(i).Children;
                end

                copyobj(axesChild.Children,app.AX(i,1),"legacy");

                app.AX(i,1).UserData = i;

                app.TileDropDown.Items = [app.TileDropDown.Items, num2str(i)];

%                 AX = app.figHandles(i).Children;
%                 AX.Parent = app.MultiPanel;
%                 AX.Layout.Tile=i;

%                 app.figHandles(i).Children.Layout.Tile = i;
%                 plot(app.figHandles(i,1).XData,app.figHandles(i,1).YData)
            end

            TileDropDownValueChanged(app);
%             % Find all children objects
%             axesIdx = strcmp(app.figHandles.Children,'Axes');
% 
%             Gobjects = app.figHandles(axesIdx,1).Children;
%             sz = size(Gobjects);
% 
%             types = strings(sz);
% 
%             for i = 1:sz(1)
%                 types(i,1) = string(Gobjects(i,1).Type);
%             end
% 
%             app.AxesObjectsDropDown.Items = types;
%             selectedObj = types(1,1);
% 
%             tst = app.currentHandle.Children;
            %             listener(g,'CurrentFigure','PostSet',@(src,event)updateFigures(app,src,event));
        end
        
        % Function to hold the general setup done on startup for new figs
        function generalSetup(app)
            % Set initial fig properties
            app.fig = gcf;
            app.ax = gca;

            app.fig.Color = 'white';
            app.fig.WindowStyle = 'normal';
            app.fig.Units = 'centimeters';

            % Set initial axes properties
            app.ax.Color = 'white';
            app.ax.LineWidth = 1.5;
            app.ax.XGrid = 'off';
            app.ax.YGrid = 'off';
            app.ax.Box = 'on';
            app.ax.FontName = 'Arial';
            app.ax.Units = 'centimeters';
            app.ax.XMinorTick = 'on';
            app.ax.YMinorTick = 'on';
            app.ax.TickLength = [0.03 0.01];
            app.ax.PositionConstraint = 'outerposition';

            app.initialX = app.ax.XLim;
            app.initialY = app.ax.YLim;

            if app.LowerXEditField.Value == 0 && app.UpperXEditField.Value == 1
                app.LowerXEditField.Value = app.initialX(1);
                app.UpperXEditField.Value = app.initialX(2);
            end
            if app.LowerYEditField.Value == 0 && app.UpperYEditField.Value == 1
                app.LowerYEditField.Value = app.initialY(1);
                app.UpperYEditField.Value = app.initialY(2);
            end
        end
        
        % Functions to choose/set colours
        function colors = distinguishable_colors(app,n_colors,bg,func)
            % DISTINGUISHABLE_COLORS: pick colors that are maximally perceptually distinct
            %
            % When plotting a set of lines, you may want to distinguish them by color.
            % By default, Matlab chooses a small set of colors and cycles among them,
            % and so if you have more than a few lines there will be confusion about
            % which line is which. To fix this problem, one would want to be able to
            % pick a much larger set of distinct colors, where the number of colors
            % equals or exceeds the number of lines you want to plot. Because our
            % ability to distinguish among colors has limits, one should choose these
            % colors to be "maximally perceptually distinguishable."
            %
            % This function generates a set of colors which are distinguishable
            % by reference to the "Lab" color space, which more closely matches
            % human color perception than RGB. Given an initial large list of possible
            % colors, it iteratively chooses the entry in the list that is farthest (in
            % Lab space) from all previously-chosen entries. While this "greedy"
            % algorithm does not yield a global maximum, it is simple and efficient.
            % Moreover, the sequence of colors is consistent no matter how many you
            % request, which facilitates the users' ability to learn the color order
            % and avoids major changes in the appearance of plots when adding or
            % removing lines.
            %
            % Syntax:
            %   colors = distinguishable_colors(n_colors)
            % Specify the number of colors you want as a scalar, n_colors. This will
            % generate an n_colors-by-3 matrix, each row representing an RGB
            % color triple. If you don't precisely know how many you will need in
            % advance, there is no harm (other than execution time) in specifying
            % slightly more than you think you will need.
            %
            %   colors = distinguishable_colors(n_colors,bg)
            % This syntax allows you to specify the background color, to make sure that
            % your colors are also distinguishable from the background. Default value
            % is white. bg may be specified as an RGB triple or as one of the standard
            % "ColorSpec" strings. You can even specify multiple colors:
            %     bg = {'w','k'}
            % or
            %     bg = [1 1 1; 0 0 0]
            % will only produce colors that are distinguishable from both white and
            % black.
            %
            %   colors = distinguishable_colors(n_colors,bg,rgb2labfunc)
            % By default, distinguishable_colors uses the image processing toolbox's
            % color conversion functions makecform and applycform. Alternatively, you
            % can supply your own color conversion function.
            %
            % Example:
            %   c = distinguishable_colors(25);
            %   figure
            %   image(reshape(c,[1 size(c)]))
            %
            % Example using the file exchange's 'colorspace':
            %   func = @(x) colorspace('RGB->Lab',x);
            %   c = distinguishable_colors(25,'w',func);

            % Copyright 2010-2011 by Timothy E. Holy

            % Parse the inputs
            if (nargin < 3)
                bg = [1 1 1];  % default white background
            else
                if iscell(bg)
                    % User specified a list of colors as a cell aray
                    bgc = bg;
                    for i = 1:length(bgc)
                        bgc{i} = parsecolor(app,bgc{i});
                    end
                    bg = cat(1,bgc{:});
                else
                    % User specified a numeric array of colors (n-by-3)
                    bg = parsecolor(app, bg);
                end
            end

            % Generate a sizable number of RGB triples. This represents our space of
            % possible choices. By starting in RGB space, we ensure that all of the
            % colors can be generated by the monitor.
            n_grid = 30;  % number of grid divisions along each axis in RGB space
            x = linspace(0,1,n_grid);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > size(rgb,1)/3)
                error('You can''t readily distinguish that many colors');
            end

            % Convert to Lab color space, which more closely represents human
            % perception
            if (nargin > 3)
                lab = func(rgb);
                bglab = func(bg);
            else
                C = makecform('srgb2lab');
                lab = applycform(rgb,C);
                bglab = applycform(bg,C);
            end

            % If the user specified multiple background colors, compute distances
            % from the candidate colors to the background colors
            mindist2 = inf(size(rgb,1),1);
            for i = 1:size(bglab,1)-1
                dX = bsxfun(@minus,lab,bglab(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end

            % Iteratively pick the color that maximizes the distance to the nearest
            % already-picked color
            colors = zeros(n_colors,3);
            lastlab = bglab(end,:);   % initialize by making the "previous" color equal to background
            for i = 1:n_colors
                dX = bsxfun(@minus,lab,lastlab); % displacement of last from all colors on list
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
                colors(i,:) = rgb(index,:);  % save for output
                lastlab = lab(index,:);  % prepare for next iteration
            end
        end

        function c = parsecolor(app,s)
            if ischar(s)
                c = colorstr2rgb(app,s);
            elseif isnumeric(s) && size(s,2) == 3
                c = s;
            else
                error('MATLAB:InvalidColorSpec','Color specification cannot be parsed.');
            end
        end

        function c = colorstr2rgb(~,c)
            % Convert a color string to an RGB value.
            % This is cribbed from Matlab's whitebg function.
            % Why don't they make this a stand-alone function?
            rgbspec = [1 0 0;0 1 0;0 0 1;1 1 1;0 1 1;1 0 1;1 1 0;0 0 0];
            cspec = 'rgbwcmyk';
            k = find(cspec==c(1));
            if isempty(k)
                error('MATLAB:InvalidColorString','Unknown color string.');
            end
            if k~=3 || length(c)==1
                c = rgbspec(k,:);
            elseif length(c)>2
                if strcmpi(c(1:3),'bla')
                    c = [0 0 0];
                elseif strcmpi(c(1:3),'blu')
                    c = [0 0 1];
                else
                    error('MATLAB:UnknownColorString', 'Unknown color string.');
                end
            end
        end

        % Function to set data properties
        function setDataProp(app)
            %% Set data properties
            app.objects = []; % Erase previous data

            % Find all objects in axes and size
            Objects = flipud(allchild(app.ax));

            for i = 1:size(Objects,1)
                if strcmp(Objects(i,1).Type,"constantline")
                    Objects(i) = []; % Erase any ylines from list
                end
            end

            % Find type
            try
                type = Objects.Type;
                typeString = convertCharsToStrings(type(1,:));
            catch
                % If line is mixed with scatter (e.g. in a fit plot)
                for i = 1:size(Objects,1)
                    %                     typeArray(i,1) = convertCharsToStrings(Objects(i,1).Type);
                    if strcmp(Objects(i,1).Type,"line")
                        Objects(i) = []; % Erase any fit lines from list and only edit scatter
                    end
                end
                typeString = 'scatter';
            end

            app.Num = size(Objects,1);

            % Add check in case app is started first and updated with 0x0 str array (app.legends)
            if isempty(app.legends) == 1
                % Initialize empty properties to default values
                num1 = size(findobj(app.fig,'Type','Line'),1);
                num2 = size(findobj(app.fig,'Type','Scatter'),1);
        
                nums = num1+num2;
                app.legends = strings(nums,1);
            end

            % Determine the type of plot (LINE?)
            if strcmp(typeString, 'line') == 1
                % Alter GUI accordingly to each type
                app.LineWidthEditField.Editable = 1;
                app.LineWidthEditField.Enable = 1;
                app.LineWidthEditFieldLabel.Enable = 1;
                app.LineStyleDropDown.Editable = 1;
                app.LineStyleDropDown.Enable = 1;
                app.LineStyleDropDownLabel.Enable = 1;


                if isempty(app.ColorPallet)
                    app.ColorPallet = distinguishable_colors(app,app.Num);
                end

                for count = 1:1:app.Num
                    Objects(count).Marker = app.Marker;
                    Objects(count).MarkerSize = app.Size;
                    Objects(count).LineWidth = app.Width;
                    Objects(count).Color = app.ColorPallet(count,:);
                    Objects(count).DisplayName = app.legends(count,:);
                end

            elseif strcmp(typeString, 'scatter') == 1 % Determine the type of plot (SCATTER?)

                % Alter GUI accordingly to each type
                app.MarkerTypeDropDown.Editable = 1;
                app.MarkerTypeDropDown.Enable = 1;
                app.MarkerTypeDropDownLabel.Enable = 1;
                app.MarkerSizeEditField.Editable = 1;
                app.MarkerSizeEditField.Enable = 1;
                app.MarkerSizeEditFieldLabel.Enable = 1;

                app.LineWidthEditField.Editable = 0;
                app.LineWidthEditField.Enable = 0;
                app.LineWidthEditFieldLabel.Enable = 0;
                app.LineStyleDropDown.Editable = 0;
                app.LineStyleDropDown.Enable = 0;
                app.LineStyleDropDownLabel.Enable = 0;

                % Then apply properties
                if isempty(app.ColorPallet)
                    app.ColorPallet = distinguishable_colors(app,app.Num);
                end

                for count = 1:1:app.Num
                    Objects(count).Marker = app.Marker;
                    Objects(count).SizeData = app.Size;
                    Objects(count).MarkerEdgeColor = app.ColorPallet(count,:);
                    Objects(count).MarkerFaceColor = Objects(count).MarkerEdgeColor;
                    Objects(count).DisplayName = app.legends(count,:);
                end
            end

            l = legend(Objects,'Location','best','fontsize',12,'Box','off');
            if app.legends(count,:) == ""
                l.Visible = false;
            else
                l.Visible = true;
            end

            app.objects = Objects;
        end
        
        function deletedFigures(app,src,~)
            delete(src);
            if isvalid(app) == 1
                update(app)
            end
        end
        
        function enable_disable(app,bool)
            if bool == 0
                app.SpacingDropDown.Enable = "off";
                app.PaddingDropDown.Enable = "off";
                app.TransposeDimButtonGroup.Enable = "off";
                app.TitleEditField.Enable = "off";
                app.SubtitleEditField.Enable = "off";
                app.YLabelEditField.Enable = "off";
                app.XLabelEditField.Enable = "off";
                app.TileDropDown.Enable = "off";
                app.LocationEditField.Enable = "off";
                app.SpanEditField.Enable = "off";
                app.TileTitle.Enable = "off";
                app.TileYLabel.Enable = "off";
                app.TileXLabel.Enable = "off";
            else
                app.SpacingDropDown.Enable = "on";
                app.PaddingDropDown.Enable = "on";
                app.TransposeDimButtonGroup.Enable = "on";
                app.TitleEditField.Enable = "on";
                app.SubtitleEditField.Enable = "on";
                app.YLabelEditField.Enable = "on";
                app.XLabelEditField.Enable = "on";
                app.TileDropDown.Enable = "on";
                app.LocationEditField.Enable = "on";
                app.SpanEditField.Enable = "on";
                app.TileTitle.Enable = "on";
                app.TileYLabel.Enable = "on";
                app.TileXLabel.Enable = "on";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             prompt = {'Enter width:','Enter height:'};
%             dlgtitle = 'Input TiledLayout Size';
%             dims = [1 35];
%             definput = {'2','2'};
%             answer = inputdlg(prompt,dlgtitle,dims,definput);
% 
%             n = str2double(answer{1});
%             m = str2double(answer{2});

            app.MultiPanel = tiledlayout(app.CenterPanel,'flow');
%             app.MultiPanel = tiledlayout(app.CenterPanel,m,n);

            % Check if MATLAB addons are installed
            if matlab.addons.isAddonEnabled('Image Processing Toolbox') == 0
                uialert(app.MultiPanelFigUIFigure,"Please install the 'Image Processing Toolbox' to use this app");
                pause(5);
                delete(app);
            end

            % Find and numerically label handles of all open figures
            auto = 0;
            g = groot;
            if isempty(g.Children)
                uialert(app.MultiPanelFigUIFigure,"There are no figures open. Please open one and update the selection","Empty Handle","Icon","warning");
                enable_disable(app,0); % Disable GUI elements that can't be used until updated
                auto = 1;
            else
                findFigures(app,[],[]);
                
                I = str2double(app.TileDropDown.Value);
                app.LocationEditField.Value = I;
                app.SpanEditField.Value = num2str(app.AX(I,1).Layout.TileSpan);
            end

            % General setup and styling
%             generalSetup(app);

%             % Initialize empty properties to default values
%             num1 = size(findobj(app.fig,'Type','Line'),1);
%             num2 = size(findobj(app.fig,'Type','Scatter'),1);
% 
%             nums = num1+num2;
%             app.legends = strings(nums,1);
% 
%             app.Size = 40;
%             app.Width = 2;
% 
%             if isempty(findobj(app.fig,'Type','Scatter')) == 0
%                 app.MarkerTypeDropDown.Value = 'Square';
%                 app.Marker = 'square';
%             else
%                 app.Marker = 'none';
%             end
%             app.LineStyle = '--';
% %             app.ScriptButton.UserData = 0; % To count each time pressed
% 
%             % Set data properties based on plot type (line or scatter)
%             if auto == 0
%                 %                 % Test to make sure gca is populated
%                 %                 objects = flipud(allchild(app.ax));
%                 %                 try
%                 %                     objects.Type;
%                 %                 catch
%                 %                     % If somehow gca is not zero but empty
%                 %                     uialert(app.MultiPanelFigUIFigure,"Please close your figure and try again.", "Figure Children Error","Icon","error");
%                 %                     return
%                 %                 end
% 
%                 setDataProp(app);
%             end
% 
%             app.ax.FontSize = 14; % This unsuspecting command changes limit functionality. KEEP HERE
% 
%             % Get rid of extra white space around figure
%             CurrentFigPos = app.fig.Position;
% 
%             % Default aspect ratio
%             app.ax.OuterPosition = [0 0 14.8167 8];
%             app.fig.Position = [CurrentFigPos(1) CurrentFigPos(2) 14.8167 8];
% 
%             % Changing limits back since functions above skew them
%             app.ax.XLim = app.initialX;
%             app.ax.YLim = app.initialY;
% 
%             % GUI should reflect default values
%             app.LowerXEditField.Value = app.ax.XLim(1);
%             app.UpperXEditField.Value = app.ax.XLim(2);
%             app.LowerYEditField.Value = app.ax.YLim(1);
%             app.UpperYEditField.Value = app.ax.YLim(2);
%             app.MarkerSizeEditField.Value = app.Size;
%             app.LineWidthEditField.Value = app.Width;

            if auto == 1
                close(gcf);
                return;
            end
        end

        % Value changed function: SpacingDropDown
        function SpacingDropDownValueChanged(app, event)
            value = app.SpacingDropDown.Value;
            
            app.MultiPanel.TileSpacing = value;
        end

        % Value changed function: PaddingDropDown
        function PaddingDropDownValueChanged(app, event)
            value = app.PaddingDropDown.Value;
            
            app.MultiPanel.Padding = value;
        end

        % Selection changed function: TransposeDimButtonGroup
        function TransposeDimButtonGroupSelectionChanged(app, event)
            selectedButton = app.TransposeDimButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,"Rowmajor")
                app.MultiPanel.TileIndexing = "rowmajor";
            else
                app.MultiPanel.TileIndexing = "columnmajor";
            end
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.MultiPanel.Title.String = value;
        end

        % Value changed function: SubtitleEditField
        function SubtitleEditFieldValueChanged(app, event)
            value = app.SubtitleEditField.Value;
            app.MultiPanel.Subtitle.String = value;
        end

        % Value changed function: YLabelEditField
        function YLabelEditFieldValueChanged(app, event)
            value = app.YLabelEditField.Value;
            app.MultiPanel.YLabel.String = value;
        end

        % Value changed function: XLabelEditField
        function XLabelEditFieldValueChanged(app, event)
            value = app.XLabelEditField.Value;
            app.MultiPanel.XLabel.String = value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
%             [file,path] = uiputfile;
            [file,path] = uiputfile('*.*', 'Save Image As');
            exportgraphics(app.MultiPanel,[path,file,'.png']);
            exportgraphics(app.MultiPanel,[path,file,'.emf']);
        end

        % Value changed function: TileDropDown
        function TileDropDownValueChanged(app, event)
            i = str2double(app.TileDropDown.Value);
            
            app.LocationEditField.Value = app.AX(i,1).Layout.Tile;
            app.SpanEditField.Value = num2str(app.AX(i,1).Layout.TileSpan);
            app.TileTitle.Value = app.AX(i,1).Title.String;
            app.TileYLabel.Value = app.AX(i,1).YLabel.String;
            app.TileXLabel.Value = app.AX(i,1).XLabel.String;
            app.FontSizeEditField.Value = app.AX(i,1).FontSize;
            app.AxesBoxCheckBox.Value = app.AX(i,1).Box;

            try % Only when Legend exists
                app.LegendCheckBox.Value = app.AX(i,1).Legend.Visible;
            catch e
                disp(e)
            end

            if strcmp(app.AX(i,1).XTickLabelMode,'auto')
                app.XTickLabelsCheckBox.Value = 1;
            else
                app.XTickLabelsCheckBox.Value = 0;
            end

            if strcmp(app.AX(i,1).YTickLabelMode,'auto')
                app.YTickLabelsCheckBox.Value = 1;
            else
                app.YTickLabelsCheckBox.Value = 0;
            end

            app.XGridLinesCheckBox.Value = app.AX(i,1).XGrid;
            app.YGridLinesCheckBox.Value = app.AX(i,1).YGrid;
            app.XMinorTicksCheckBox.Value = app.AX(i,1).XMinorTick;
            app.YMinorTicksCheckBox.Value = app.AX(i,1).YMinorTick;
        end

        % Value changed function: LocationEditField
        function LocationEditFieldValueChanged(app, event)
            old = str2double(app.TileDropDown.Value);
            new = app.LocationEditField.Value;

            app.AX(old,1).Layout.Tile = new;

            sz = size(app.AX,1);

            if old < new % Moving tile ahead so subtract 1 from earlier tiles
% %                 for i = 1:new
% %                     app.AX(i,1).Layout
% %                 end
%                 i = 1:new;
%                 i(old) = [];
%                 i = [i,new+1:sz];

                i1 = 1:old;
                i1(end) = [];
                i2 = old+1:new;

                if new ~= sz
                    i3 = new+1:sz;
                else
                    i3 = [];
                end

                i = [i1,i2,old,i3];
            else % Moving tile before so add 1 to every tile ahead of new
%                 for i = new:sz
%                     
%                 end
                i3 = sz:-1:old;
                i3(end) = [];

                i3 = flip(i3);

                i2 = old-1:-1:new;
                
                i2 = flip(i2);

                if new ~= 1
                    i1 = new-1:-1:1;
                    i1 = flip(i1);
                else
                    i1 = [];
                end
            
                i = [i1,old,i2,i3];
            end

            % Reindex array of Axes
            app.AX = app.AX(i);

            % Set tile locations as order of array
            for ind = 1:sz
                app.AX(ind,1).Layout.Tile = ind;
            end

            % Update GUI fields
            app.TileDropDown.Value = num2str(new);
            app.LocationEditField.Value = new;
        end

        % Value changed function: SpanEditField
        function SpanEditFieldValueChanged(app, event)
            value = app.SpanEditField.Value;
            i = str2double(app.TileDropDown.Value);

            if isempty(value)
                return
            end

            str = strsplit(value,{',',' '});

            span = [str2double(str{1}), str2double(str{2})];
            
            try
                app.AX(i,1).Layout.TileSpan = span;
            catch e
                disp(e);
                uialert(app.MultiPanelFigUIFigure,"Problem with span input, please only input scalar values.","Span Error","Icon","error");
            end
        end

        % Value changed function: TileTitle
        function TileTitleValueChanged(app, event)
            value = app.TileTitle.Value;
            i = str2double(app.TileDropDown.Value);
            
            app.AX(i).Title.String = value;
        end

        % Value changed function: TileYLabel
        function TileYLabelValueChanged(app, event)
            value = app.TileYLabel.Value;
            i = str2double(app.TileDropDown.Value);
            
            app.AX(i).YLabel.String = value;
        end

        % Value changed function: TileXLabel
        function TileXLabelValueChanged(app, event)
            value = app.TileXLabel.Value;
            i = str2double(app.TileDropDown.Value);
            
            app.AX(i).XLabel.String = value;
        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i).FontSize = value;
        end

        % Value changed function: AxesBoxCheckBox
        function AxesBoxCheckBoxValueChanged(app, event)
            value = app.AxesBoxCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i).Box = value;
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            value = app.LegendCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            try
                app.AX(i,1).Legend.Visible = value;
            catch e
                app.LegendCheckBox.Value = 0;
                disp("Legend must exist to turn on or off")
                disp(e)
            end
        end

        % Value changed function: XTickLabelsCheckBox
        function XTickLabelsCheckBoxValueChanged(app, event)
            value = app.XTickLabelsCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            if value == 1
                app.AX(i,1).XTickLabelMode = 'auto';
            else
                app.AX(i,1).XTickLabelMode = 'manual';
                app.AX(i,1).XTickLabel = {};
            end
        end

        % Value changed function: YTickLabelsCheckBox
        function YTickLabelsCheckBoxValueChanged(app, event)
            value = app.YTickLabelsCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            if value == 1
                app.AX(i,1).YTickLabelMode = 'auto';
            else
                app.AX(i,1).YTickLabelMode = 'manual';
                app.AX(i,1).YTickLabel = {};
            end
        end

        % Value changed function: XGridLinesCheckBox
        function XGridLinesCheckBoxValueChanged(app, event)
            value = app.XGridLinesCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i,1).XGrid = value;

            if app.XMinorTicksCheckBox.Value == 1
                app.AX(i,1).XMinorGrid = value;
            else
                app.AX(i,1).XMinorGrid = 0;
            end
        end

        % Value changed function: YGridLinesCheckBox
        function YGridLinesCheckBoxValueChanged(app, event)
            value = app.YGridLinesCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i,1).YGrid = value;

            if app.YMinorTicksCheckBox.Value == 1
                app.AX(i,1).YMinorGrid = value;
            else
                app.AX(i,1).YMinorGrid = 0;
            end
        end

        % Value changed function: XMinorTicksCheckBox
        function XMinorTicksCheckBoxValueChanged(app, event)
            value = app.XMinorTicksCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i,1).XMinorTick = value;

            if app.XGridLinesCheckBox.Value == 1
                app.AX(i,1).XMinorGrid = value;
            else
                app.AX(i,1).XMinorGrid = 0;
            end
        end

        % Value changed function: YMinorTicksCheckBox
        function YMinorTicksCheckBoxValueChanged(app, event)
            value = app.YMinorTicksCheckBox.Value;
            i = str2double(app.TileDropDown.Value);

            app.AX(i,1).YMinorTick = value;

            if app.YGridLinesCheckBox.Value == 1
                app.AX(i,1).YMinorGrid = value;
            else
                app.AX(i,1).YMinorGrid = 0;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Reset TileLayout
            delete(app.MultiPanel);
            app.MultiPanel = [];
            app.MultiPanel = tiledlayout(app.CenterPanel,'flow');
            app.TileDropDown.Items = {};
            app.TileDropDown.Value = {};

            app.TileFlowButtonGroup.SelectedObject = app.AutoButton;

            app.TitleEditField.Value = "";
            app.SubtitleEditField.Value = "";
            app.YLabelEditField.Value = "";
            app.XLabelEditField.Value = "";
            app.SpacingDropDown.Value = "loose";
            app.PaddingDropDown.Value = "loose";
            app.TransposeDimButtonGroup.SelectedObject = app.RowmajorButton;

            g = groot;
            
            if isempty(g.Children)
                uialert(app.OnePanelFigUIFigure,"There is no figure open. Please open one and update the selection. Note the script save number has also reset","Empty Handle","Icon","warning");
                enable_disable(app,0); % Disable GUI elements that can't be used until updated
                return
            else
                enable_disable(app,1); % Enable GUI elements
                findFigures(app,[],[]);
                I = str2double(app.TileDropDown.Value);
                app.LocationEditField.Value = I;
                app.SpanEditField.Value = num2str(app.AX(I,1).Layout.TileSpan);
            end
        end

        % Selection changed function: TileFlowButtonGroup
        function TileFlowButtonGroupSelectionChanged(app, event)
            selectedButton = app.TileFlowButtonGroup.SelectedObject;
            
            if selectedButton == app.ManualButton
                answers = inputdlg({'Enter how many rows: ', 'Enter how many columns: '},'Manual Tile Size',[1 35]);

                if answers{1} ~= 0 && answers{2} ~=0
                    try
                        m = str2double(answers{1});
                        n = str2double(answers{2});
                    catch e
                        disp(e);
                        uialert(app.MultiPanelFigUIFigure,"Please input positive scalar values for the Grid Size","Grid Size Error","Icon","error");
                    end
    
                    findFigures(app,m,n);
                end
            else
                UpdateButtonPushed(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MultiPanelFigUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {545, 545, 545};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {545, 545};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {200, '1x', 210};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MultiPanelFigUIFigure = uifigure('Visible', 'off');
            app.MultiPanelFigUIFigure.Name = 'MultiPanelFig';
            app.MultiPanelFigUIFigure.Icon = fullfile(pathToMLAPP, 'MultiPanelFig_resources', 'logo.png');
            app.MultiPanelFigUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MultiPanelFigUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PanelLabels = uilabel(app.LeftPanel);
            app.PanelLabels.Text = 'Panel Labels';
            app.PaddingLabel = uilabel(app.LeftPanel);
            app.PaddingLabel.Text = 'Padding';
            app.SpacingLabel = uilabel(app.LeftPanel);
            app.SpacingLabel.Text = 'Spacing';
            app.PanelPropertiesLabel = uilabel(app.LeftPanel);
            app.PanelPropertiesLabel.Text = 'Panel Properties';
            app.SpacingDropDown = uidropdown(app.LeftPanel);
            app.SpacingDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.SpacingDropDown.ValueChangedFcn = createCallbackFcn(app, @SpacingDropDownValueChanged, true);
            app.SpacingDropDown.Value = 'loose';
            app.PaddingDropDown = uidropdown(app.LeftPanel);
            app.PaddingDropDown.Items = {'loose', 'compact', 'tight'};
            app.PaddingDropDown.ValueChangedFcn = createCallbackFcn(app, @PaddingDropDownValueChanged, true);
            app.PaddingDropDown.Value = 'loose';
            app.TransposeDimButtonGroup = uibuttongroup(app.LeftPanel);
            app.TransposeDimButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TransposeDimButtonGroupSelectionChanged, true);
            app.TransposeDimButtonGroup.Title = 'Transpose Dim';
            app.RowmajorButton = uiradiobutton(app.TransposeDimButtonGroup);
            app.RowmajorButton.Text = 'Rowmajor';
            app.RowmajorButton.Value = true;
            app.ColumnmajorButton = uiradiobutton(app.TransposeDimButtonGroup);
            app.ColumnmajorButton.Text = 'Columnmajor';
            app.TitleEditFieldLabel = uilabel(app.LeftPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.LeftPanel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SubtitleEditFieldLabel = uilabel(app.LeftPanel);
            app.SubtitleEditFieldLabel.Text = 'Subtitle';
            app.SubtitleEditField = uieditfield(app.LeftPanel, 'text');
            app.SubtitleEditField.ValueChangedFcn = createCallbackFcn(app, @SubtitleEditFieldValueChanged, true);
            app.XLabelEditFieldLabel = uilabel(app.LeftPanel);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.XLabelEditField = uieditfield(app.LeftPanel, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.YLabelEditFieldLabel = uilabel(app.LeftPanel);
            app.YLabelEditFieldLabel.Text = 'YLabel';
            app.YLabelEditField = uieditfield(app.LeftPanel, 'text');
            app.YLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YLabelEditFieldValueChanged, true);
            app.TileFlowButtonGroup = uibuttongroup(app.LeftPanel);
            app.TileFlowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TileFlowButtonGroupSelectionChanged, true);
            app.TileFlowButtonGroup.Title = 'Tile Flow';
            app.AutoButton = uitogglebutton(app.TileFlowButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uitogglebutton(app.TileFlowButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UpdateLabel = uilabel(app.RightPanel);
            app.UpdateLabel.Text = 'Update';
            app.SelectionLabel = uilabel(app.RightPanel);
            app.SelectionLabel.Text = 'Selection';
            app.TilePropertiesLabel = uilabel(app.RightPanel);
            app.TilePropertiesLabel.Text = 'Tile Properties';
            app.TileLabel = uilabel(app.RightPanel);
            app.TileLabel.Text = 'Tile #';
            app.LocationEditFieldLabel = uilabel(app.RightPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.TileDropDown = uidropdown(app.RightPanel);
            app.TileDropDown.Items = {};
            app.TileDropDown.ValueChangedFcn = createCallbackFcn(app, @TileDropDownValueChanged, true);
            app.TileDropDown.Value = {};
            app.LocationEditField = uieditfield(app.RightPanel, 'numeric');
            app.LocationEditField.ValueChangedFcn = createCallbackFcn(app, @LocationEditFieldValueChanged, true);
            app.SpanEditField_2Label = uilabel(app.RightPanel);
            app.SpanEditField_2Label.Text = 'Span';
            app.InputasrowcolLabel = uilabel(app.RightPanel);
            app.InputasrowcolLabel.Text = '(Input as: row, col)';
            app.SpanEditField = uieditfield(app.RightPanel, 'text');
            app.SpanEditField.ValueChangedFcn = createCallbackFcn(app, @SpanEditFieldValueChanged, true);
            app.AxesLabels = uilabel(app.RightPanel);
            app.AxesLabels.Text = 'Axes Labels';
            app.TitleEditField_2Label = uilabel(app.RightPanel);
            app.TitleEditField_2Label.Text = 'Title';
            app.TileTitle = uieditfield(app.RightPanel, 'text');
            app.TileTitle.ValueChangedFcn = createCallbackFcn(app, @TileTitleValueChanged, true);
            app.YLabelEditField_2Label = uilabel(app.RightPanel);
            app.YLabelEditField_2Label.Text = 'YLabel';
            app.TileYLabel = uieditfield(app.RightPanel, 'text');
            app.TileYLabel.ValueChangedFcn = createCallbackFcn(app, @TileYLabelValueChanged, true);
            app.XLabelEditField_2Label = uilabel(app.RightPanel);
            app.XLabelEditField_2Label.Text = 'XLabel';
            app.TileXLabel = uieditfield(app.RightPanel, 'text');
            app.TileXLabel.ValueChangedFcn = createCallbackFcn(app, @TileXLabelValueChanged, true);
            app.FontSizeEditFieldLabel = uilabel(app.RightPanel);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.ToggleAxesPropertiesLabel = uilabel(app.RightPanel);
            app.ToggleAxesPropertiesLabel.Text = 'Toggle Axes Properties';
            app.YMinorTicksCheckBox = uicheckbox(app.RightPanel);
            app.YMinorTicksCheckBox.ValueChangedFcn = createCallbackFcn(app, @YMinorTicksCheckBoxValueChanged, true);
            app.YMinorTicksCheckBox.Text = 'YMinor Ticks';
            app.AxesBoxCheckBox = uicheckbox(app.RightPanel);
            app.AxesBoxCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxesBoxCheckBoxValueChanged, true);
            app.AxesBoxCheckBox.Text = 'Axes Box';
            app.LegendCheckBox = uicheckbox(app.RightPanel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.XTickLabelsCheckBox = uicheckbox(app.RightPanel);
            app.XTickLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @XTickLabelsCheckBoxValueChanged, true);
            app.XTickLabelsCheckBox.Text = 'XTick Labels';
            app.YTickLabelsCheckBox = uicheckbox(app.RightPanel);
            app.YTickLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @YTickLabelsCheckBoxValueChanged, true);
            app.YTickLabelsCheckBox.Text = 'YTick Labels';
            app.XGridLinesCheckBox = uicheckbox(app.RightPanel);
            app.XGridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @XGridLinesCheckBoxValueChanged, true);
            app.XGridLinesCheckBox.Text = 'XGrid Lines';
            app.YGridLinesCheckBox = uicheckbox(app.RightPanel);
            app.YGridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @YGridLinesCheckBoxValueChanged, true);
            app.YGridLinesCheckBox.Text = 'YGrid Lines';
            app.XMinorTicksCheckBox = uicheckbox(app.RightPanel);
            app.XMinorTicksCheckBox.ValueChangedFcn = createCallbackFcn(app, @XMinorTicksCheckBoxValueChanged, true);
            app.XMinorTicksCheckBox.Text = 'XMinor Ticks';
            % Show the figure after all components are created
            app.MultiPanelFigUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiPanelFig

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MultiPanelFigUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MultiPanelFigUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiPanelFigUIFigure)
        end
    end
end